classdef colocGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HelpButton              matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        StatisticsPanel         matlab.ui.container.Panel
        RunforAllCheckBox       matlab.ui.control.CheckBox
        Button                  matlab.ui.control.Button
        PathEditField           matlab.ui.control.EditField
        PathEditFieldLabel      matlab.ui.control.Label
        StatisticalTestButton   matlab.ui.control.Button
        SaveTestsCheckBox       matlab.ui.control.CheckBox
        ResultsPanel            matlab.ui.container.Panel
        ViewResultsButton       matlab.ui.control.Button
        ResultOptionsButton     matlab.ui.control.Button
        ColocPanel              matlab.ui.container.Panel
        SelectChannelPanel_2    matlab.ui.container.Panel
        ButtonGroup_2           matlab.ui.container.ButtonGroup
        ButtonN2                matlab.ui.control.RadioButton
        ButtonP2                matlab.ui.control.RadioButton
        ChannelDropDown_2       matlab.ui.control.DropDown
        SelectChannelPanel      matlab.ui.container.Panel
        ChannelDropDown         matlab.ui.control.DropDown
        ButtonGroup             matlab.ui.container.ButtonGroup
        ButtonN1                matlab.ui.control.RadioButton
        ButtonP1                matlab.ui.control.RadioButton
        AnalysisFunctionPanel   matlab.ui.container.Panel
        RunButton               matlab.ui.control.Button
        FunctionDropDown        matlab.ui.control.DropDown
        RandomizationsPanel     matlab.ui.container.Panel
        NumRand_EditField       matlab.ui.control.NumericEditField
        ColocRadiusPixelsPanel  matlab.ui.container.Panel
        SearchRadius_EditField  matlab.ui.control.NumericEditField
        DetectionProcessPanel   matlab.ui.container.Panel
        DP2_DropDown            matlab.ui.control.DropDown
        DP1_DropDown            matlab.ui.control.DropDown
        MoviesPanel             matlab.ui.container.Panel
        SaveasListButton        matlab.ui.control.Button
        MovieData_ListBox       matlab.ui.control.ListBox
        ClearButton             matlab.ui.control.Button
        LoadMD_Button           matlab.ui.control.Button
        LoadML_Button           matlab.ui.control.Button
        MovieList_ListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        DialogApp2
        
        MD                      % MovieData Object Array
        moviePaths
        ML                      % MovieList Object Array
        listPaths
        
        CH1 = 'P';
        CH2 = 'P';
        
        %ppX = 1;                %Pt2Pt channel numbers
        %ppY = 2;
        ch1Num
        ch2Num
        ppX
        ppY
        
        % Values were 2 and 1 before deletion 240815 TP
        pbBlob                    %Pt2Blob channel numbers
        pbPt
        
        bbX                       %Blob2Blob channel numbers
        bbY
       
        dP1 = 'PointSource'            % Detection Process ID (PointSource or SubRes)
        dP2 = 'PointSource'
        dPGen                     % ID for Pt2Blob function where only one is needed
        
        numRand = 1;              % Number of Randomizations Parameter
        searchRad = 3;            % Search Radius Parameter
        
        funcValue;
        thresh = 3;
        threshDir
        
        userDir = pwd;
        
        % Result Variables
        resPB = 1;
        resPlot = 1;
        saveRes = 1;
        pathToSave = ' ';
        resCh = [];              % Holds chosen channels to analyze for results
        
        % Statistical Test Variables
        statPath = '';
        
    end
    
    methods (Access = private)
        
        function refreshDisplay(app)
            app.moviePaths = arrayfun(@getFullPath,app.MD,'Unif',false);
            app.listPaths = arrayfun(@getFullPath,app.ML,'Unif',false);
            app.MovieData_ListBox.Items = {app.moviePaths{:}}; %#ok<*CCAT1> 
            app.MovieList_ListBox.Items = {app.listPaths{:}};
            return 
        end
        
        function checkPunc(app)
            if (isequal(app.CH1,'P')) && (isequal(app.CH2,'P'))
                %app.ppX = 1;
                %app.ppY = 2;
                app.ppX = app.ch1Num;
                app.ppY = app.ch2Num;
            % 08/16/2024 TP, changed if to elseif
            elseif (isequal(app.CH1,'N')) && (isequal(app.CH2,'P'))
                %app.pbBlob = 1;
                %app.pbPt = 2;
                app.pbBlob = app.ch1Num;
                app.pbPt = app.ch2Num;
                app.dPGen = app.dP2;
            elseif (isequal(app.CH1,'P')) && (isequal(app.CH2,'N'))
                %app.pbBlob = 2;
                %app.pbPt = 1;
                app.pbBlob = app.ch2Num;
                app.pbPt = app.ch1Num;
                app.dPGen = app.dP1;
            elseif (app.CH1 == 'N') && (app.CH2 == 'N')   
                app.bbX = app.ch1Num;
                app.bbY = app.ch2Num;
%             dont need because assuming Blob2Blob only works for blobs so both
%             channels are assumed as blobs
%             240821 TP - added functionality for Blob2Blob function to
%             analyze other channels
            end
        end
        
        function checkFunc(app)
            if (isequal(app.CH1,'P') && isequal(app.CH2,'P'))
                app.FunctionDropDown.Items = {'Pt2Pt'};
                app.NumRand_EditField.Enable = 'off';
                app.funcValue = 'Pt2Pt';
            elseif (isequal(app.CH1,'N') && isequal(app.CH2,'N'))
                app.FunctionDropDown.Items = {'Blob2Blob'};
                app.NumRand_EditField.Enable = 'on';
                app.funcValue = 'Blob2Blob';
            elseif (isequal(app.CH1,'P') && isequal(app.CH2,'N')) || ...
               (isequal(app.CH1,'N') && isequal(app.CH2,'P'))
                app.FunctionDropDown.Items = {'Pt2Blob'};
                app.NumRand_EditField.Enable = 'off';
                app.funcValue = 'Pt2Blob';
            end
        end
        
        function saveList(app)
            if isempty(app.MD)
                warndlg('No movie selected. Please create new movie data or open existing movie data or movie list.', 'No Movie Selected', 'modal')
                return
            end
            
            if isempty(app.ML)
                movieListPath = [app.userDir filesep];
                movieListFileName = 'movieList.mat';
            else
                movieListPath = app.ML(end).movieListPath_;
                movieListFileName = app.ML(end).movieListFileName_;
            end
            
            [fileName,path] = uiputfile('*.mat','Find a place to save your movie list',...
             [movieListPath filesep movieListFileName]);
            if ~any([fileName,path]), return; end
            
            outputDir = uigetdir(path,'Select a directory to store the list analysis output');
            if isequal(outputDir,0), return; end
            
            try
                tempML = MovieList(app.MD, outputDir);
                tempML.setPath(path);
                tempML.setFilename(fileName);
                tempML.sanityCheck;
            catch ME
                msg = sprintf('%s\n\nMovie list is not saved.', ME.message);
                errordlg(msg, 'Movie List Error', 'modal')
                return
            end
            app.ML = horzcat(app.ML, tempML);
        end
        
        % 240815 TP, Adding function for drop down channel selecting
        % functionality
        function channelDropDownInit(app)
            % Uses MovieData property to get the number of channels and
            % populates the dropdown menus and initialies ppX and ppY
            
            maxChannels = numel(app.MD(1).channels_);
            numChannelsArray = (1:maxChannels);
            cellArrayChannels = arrayfun(@num2str, numChannelsArray, 'UniformOutput', false);
            
            % Populate cell arrays with values and initialize to first
            % value
            app.ChannelDropDown.Items = cellArrayChannels;
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};
            
            app.ChannelDropDown_2.Items = cellArrayChannels;
            app.ChannelDropDown_2.Value = app.ChannelDropDown_2.Items{1};
            
            % Initialize chNums to get start values
            app.ch1Num = str2double(app.ChannelDropDown.Value);
            app.ch2Num = str2double(app.ChannelDropDown_2.Value);
            
            % Uses checkPunc to immediately update in case of selecting
            % detection process first
            checkPunc(app);
            
            checkFunc(app);
            
            % Enable drop down channels for selection
            app.ChannelDropDown.Enable = 'on';
            app.ChannelDropDown_2.Enable = 'on';
        end
    end
    
    methods (Access = public)
        function updateResVar(app, resPB, resPlot, saveRes, pathToSave, resCh)
            app.resPB = resPB;
            app.resPlot = resPlot;
            app.saveRes = saveRes;
            app.pathToSave = pathToSave; 
            app.resCh = resCh;
            app.ResultOptionsButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");                 % Centers application to middle of screen
            
            app.MD = MovieData.empty(1,0);                                     % Creates empty array of MovieData objects
            app.ML = MovieList.empty(1,0);                                     % Creates empty array of MovieList objects
            
            checkFunc(app);
            
        end

        % Button pushed function: LoadMD_Button
        function LoadMD_ButtonPushed(app, event)
            filespec = {'*.mat','MATLAB Files'};
            [fileName,filePath] = uigetfile(filespec, 'Select a Movie to load', app.userDir);                    % Opens Dialogue Box for File Selection 
            drawnow;
            figure(app.UIFigure);
            
            if fileName == 0
                errordlg('No file was input' , 'File Selection Error','modal');
                return
            end
            
            if ~any([fileName filePath]), return; end
            app.userDir = filePath;
            file = [filePath fileName];
            
            if any(strcmp([filePath fileName], app.moviePaths))
                errordlg('This movie has already been selected.','File Selection Error','modal');
                return
            end
            
            try
                movieData = MovieData.load(file);                   % gets MovieData object from the .mat file
                app.MD = horzcat(app.MD, movieData);                % adds MovieData object to array of MovieData
            catch ME
                msg = sprintf('Movie: %s\n\nError: %s\n\nMovie is not successfully loaded. Please refer to movie detail and adjust your data.', [filePath fileName],ME.message);
                errordlg(msg, 'Movie error','modal');
                return
            end
            
            %240829 TP: Set initial stat path to MD output directory
            app.PathEditField.Value = app.MD.outputDirectory_;
            
            % 240815 TP, Using channelDropDownInit function
            channelDropDownInit(app);
            
            app.refreshDisplay();                           % updates items in Movie Data listbox
        end

        % Button pushed function: LoadML_Button
        function LoadML_ButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.mat');                      % Opens Dialogue Box for File Selection
            drawnow;
            figure(app.UIFigure);
            file = [filePath fileName];
%             disp(file);
            if fileName == 0
                %warning("No file was input - returning")
                errordlg('No file was input' , 'File Selection Error','modal');
                return
            end
            
            if any(strcmp([filePath fileName], app.listPaths))
                errordlg('This movie list has already been opened.','File Selection Error','modal');
                return
            end
            
            movieList = MovieList.load(file);                    % gets MovieList object from the .mat file
            app.ML = horzcat(app.ML,movieList);                  % adds MovieList object to array of MovieData         
           
            % adds movies from MovieList to MovieData Listbox/MD array
            for i = 1:length(movieList.movieDataFile_)
                dataFile = movieList.movieDataFile_(i);        
                MDobject = load(char(dataFile));
                tempMD = MDobject.MD;
                app.MD = horzcat(app.MD, tempMD);
            end
            
            % 240829 TP
            app.PathEditField.Value = app.ML.outputDirectory_;
            
            % 240815 TP
            channelDropDownInit(app);
            
            app.refreshDisplay();
        end

        % Button pushed function: SaveasListButton
        function SaveasListButtonPushed(app, event)
            if isempty(app.moviePaths)
                errordlg('No movies loaded. Please load movie data first to create a new movie list.', 'Saving Error', 'modal');
                return;
            end
            saveList(app);
            refreshDisplay(app);
            
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Confirm Clear
            user_response = questdlg('Are you sure to delete all the movies and movie lists?','Movie Listbox', 'Yes','No','Yes');
            if strcmpi('no', user_response), return; end
            
            % Clear DropDownItems and Value and disable dropdown
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown_2.Items = {};
            app.ChannelDropDown_2.Value = {};
            app.ChannelDropDown_2.Enable = 'off';
            
            % Clear Stat Path Field
            app.PathEditField.Value = '';
            
            % Disable View Results button
            app.ViewResultsButton.Enable = 'off';
            
            % Clear List Boxes
            app.MD = MovieData.empty(1,0);
            app.ML = MovieList.empty(1,0);
            app.refreshDisplay();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton1 = app.ButtonGroup.SelectedObject;
            
            if selectedButton1 == app.ButtonP1
                app.DP1_DropDown.Enable = 'on';
                app.CH1 = 'P';
            else
                app.DP1_DropDown.Enable = 'off';
                app.CH1 = 'N';
            end
            checkPunc(app);
            checkFunc(app);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton2 = app.ButtonGroup_2.SelectedObject;
            
            if selectedButton2 == app.ButtonP2
                app.DP2_DropDown.Enable = 'on';
                app.CH2 = 'P';
            else
                app.DP2_DropDown.Enable = 'off';
                app.CH2 = 'N';
            end
            checkPunc(app);
            checkFunc(app);
        end

        % Value changed function: DP1_DropDown
        function DP1_DropDownValueChanged(app, event)
            value = app.DP1_DropDown.Value;
            app.dP1 = value;
            checkPunc(app); % TP
        end

        % Value changed function: DP2_DropDown
        function DP2_DropDownValueChanged(app, event)
            value = app.DP2_DropDown.Value;
            app.dP2 = value;
            checkPunc(app); % TP
        end

        % Value changed function: SearchRadius_EditField
        function SearchRadius_EditFieldValueChanged(app, event)
            value = app.SearchRadius_EditField.Value;
            app.searchRad = value;
        end

        % Value changed function: NumRand_EditField
        function NumRand_EditFieldValueChanged(app, event)
            value = app.NumRand_EditField.Value;
            app.numRand = value;
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            app.funcValue = value;
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            app.ch1Num = str2double(value);
            checkPunc(app);
        end

        % Value changed function: ChannelDropDown_2
        function ChannelDropDown_2ValueChanged(app, event)
            value = app.ChannelDropDown_2.Value;
            app.ch2Num = str2double(value);
            checkPunc(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             if isempty(app.moviePaths)
                errordlg('No movies loaded. Please load movie data before running.','Run Analysis Error', "modal");
                return;
            end
            
            % Formatting Console Window
            fprintf('\nRunning Analysis\n\n');
            
            % Running any of the 3 functions
            switch app.FunctionDropDown.Value
                case 'Pt2Pt'
                    disp('Pt2Pt Analysis')
                    for i = 1:length(app.MD)
                        formatSpec = 'Movie %d:';
                        str = sprintf(formatSpec,i);
                        disp(str); %#ok<DSPS> 
                        colocAnalysisPt2PtMLMD(app.MD(i),'detectedChannels',[app.ppX app.ppY],'detectionProcessID',{app.dP1 app.dP2},'searchRadius',app.searchRad);
                        disp(' ');
                    end
                    msgBox("Pt2Pt Colocalization Analysis Complete","Colocalization","none");
                case 'Pt2Blob'
                    disp('Pt2Blob Analysis')
                    for i = 1:length(app.MD)
                        formatSpec = 'Movie %d:';
                        str = sprintf(formatSpec,i);
                        disp(str); %#ok<DSPS>
                        colocalMeasurePt2BlobMLMD(app.MD(i),'blobChannel',app.pbBlob,'detectionChannel',app.pbPt,'detectionProcessID',app.dPGen,'searchRadius',app.searchRad); 
                        disp(' ');
                    end
                    msgBox("Pt2Blob Colocalization Analysis Complete","Colocalization","none");
                case 'Blob2Blob'
                    disp('Blob2Blob Analysis')    
                    for i = 1:length(app.MD)
                        formatSpec = 'Movie %d:';
                        str = sprintf(formatSpec,i);
                        disp(str); %#ok<DSPS> 
                        colocalMeasureBlob2BlobMLMD(app.MD(i),'detectedChannels',[app.bbX app.bbY],'searchRadius',app.searchRad,'numRandomizations',app.numRand);
                        disp(' ');
                    end
                    msgBox("Blob2Blob Colocalization Analysis Complete","Colocalization","none");
            end
            
            if length(app.MD) > 1
                disp('Analysis Completed');
                disp(' ');
                disp(' ');
            end
            
            figure(app.UIFigure);
        end

        % Button pushed function: ResultOptionsButton
        function ResultOptionsButtonPushed(app, event)
            if isempty(app.listPaths)
                errordlg('No movie list loaded. Please load or create a new movie list first.', 'View Results Error', 'modal');
                return;
            end
            
            app.ResultOptionsButton.Enable = 'off';
            
            % TP, 240821 - Updated to automatically get analysis
            % function value in dialogapp
            app.DialogApp2 = colocGUIResults(app, app.resPB, app.resPlot, app.saveRes, app.pathToSave);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = uigetdir(app.PathEditField.Value, 'Select Folder For Statistical Test');
            if ~isequal(value, 0)
                app.PathEditField.Value = value;
            end
        end

        % Button pushed function: ViewResultsButton
        function ViewResultsButtonPushed(app, event)
            %switch app.funcValue
            %    case 'Pt2Pt'
            %        iProc = app.MD(1).getProcessIndex('ColocalizationPt2PtProcess',1,0);
            %    case 'Pt2Blob'
            %        iProc = app.MD(1).getProcessIndex('ColocalizationPt2BlobProcess',1,0);
            %    case 'Blob2Blob'
            %        iProc = app.MD(1).getProcessIndex('ColocalizationBlob2BlobProcess',1,0);
            %end
            %disp(iProc)
            
            % 20240910 TP: Updated to disconnect from using MovieData
            % properties that update frequently
            folderFuncValue = '';
            switch app.resPB
                case 1
                    folderFuncValue = 'Pt2Pt';
                case 2
                    folderFuncValue = 'Pt2Blob';
                case 3
                    folderFuncValue = 'Blob2Blob';
            end
           
            tempDirPath = app.MD.outputDirectory_; % required to get one output
            colocFilePath = fullfile(tempDirPath, ['Colocalization' folderFuncValue],...
                            ['colocalInfo' num2str(app.resCh(1)) num2str(app.resCh(2)) '.mat']);
            load(colocFilePath, 'thresholdUsed');
            
            %app.thresh = app.MD(1).processes_{1,iProc}.funParams_.SearchRadius;

            folderSpec = 'Threshold_%d';
            fld = sprintf(folderSpec, thresholdUsed);
            
            mkdir(app.pathToSave,fld);
            app.threshDir = fullfile(app.pathToSave,fld);
            
            % multiple lists

            mlCell = cell(1,length(app.ML));
            
            for i = 1:length(app.ML)
                mlCell{1,i} = app.ML(i); 
            end

            plotSaveColocResults(app.resPB,app.resPlot,app.saveRes,app.threshDir,1,app.resCh,mlCell{1,:}); %1 because in GUI
            
            % If saving results, then update stat path
            if isequal(app.saveRes, 1)
                app.PathEditField.Value = app.threshDir;
            end
            fprintf('\nResults Completed\n');
            msgboxMsg = sprintf("Compiled Results Completed\n\nStat Test Path is %s", app.PathEditField.Value);
            msgbox(msgboxMsg,"Compiled Results","none");
        end

        % Button pushed function: StatisticalTestButton
        function StatisticalTestButtonPushed(app, event)
            % TP 240830: Statistical Test Button no longer requires MD/ML
            % to be loaded before it can be used
            % if isempty(app.listPaths)
            %     errordlg('No movie list loaded. Please load or create a new movie list first.', 'View Results Error', 'modal');
            %     return;
            % end
            
            % TP 240829: Making Stat Test Independent
            % app.PathEditField is where files will be saved
            
            if isequal(app.RunforAllCheckBox.Value, 1)
                fds = fileDatastore(app.PathEditField.Value,"ReadFcn",@load,"FileExtensions",".mat");
                resArray = fds.Files;
                TF = contains(resArray, 'compiledColoc');
                resArray = resArray(TF);
                
                if isequal(app.SaveTestsCheckBox.Value,1)
                    for i = 1:length(resArray)
                        % colocStatTest(1,resArray{i},app.threshDir);
                        colocStatTest(1,resArray{i},app.PathEditField.Value);
                    end
                    msgbox("Statistic Test Completed","Statistic Test","help","modal");
                    
                else % If not saving, display all results as a series of tables     
                    for i = 1:length(resArray)
                        tempNums = regexp(resArray{i}, '\d*', 'match');
                        fileNums = tempNums{end};
                        figureTitle = ['Stat Test for Compiled Coloc Result ' fileNums];
                        
                        tbRes = colocStatTest(0,resArray{i},app.PathEditField.Value);
                        fig = figure('Name', figureTitle);
                        uitable(fig, 'Data',tbRes{:,:},'ColumnName',tbRes.Properties.VariableNames,...
                        'RowName',tbRes.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                    end
                end
            else
                % If saving, use the path in the path field to save and user
                % selects a file
                if isequal(app.SaveTestsCheckBox.Value,1)
                    colocStatTest(1,[],app.PathEditField.Value);
                else %If not saving, ask user for file and display results as a table
                   tbRes = colocStatTest(0);
                   uitable('Data',tbRes{:,:},'ColumnName',tbRes.Properties.VariableNames,...
                   'RowName',tbRes.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                end
            end
            
            fprintf('\nStatistic Test Completed\n');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            %if isunix
            %    system(['evince ' '~/matlab/applications/receptors/colocalization/GUI/doc/colocGUI_Help.pdf' ' &']);
            %elseif ispc
            %    open('~/matlab/applications/receptors/colocalization/GUI/doc/colocGUI_help.docx');
            %else
            %    msgbox(['Open ' 'colocGUI_Help.pdf' 'in the operating system'],'Product Help');
            %end
            
            % 240911 TP: Gets path of colocGUI, backs into the GUI folder, into the
            % doc folder to get the correct pdf
            
            if isunix
                delimiter = '/';
            elseif ispc
                delimiter = '\';
            else
                msgbox('Unknown Operating System');
            end
            
            currPath = mfilename('fullpath');
            tempSplit = split(currPath, delimiter);
            newTempPath = join(tempSplit(1:end-1), delimiter);
            filePath = fullfile(newTempPath{1}, 'doc', 'colocGUI_Help.pdf');
            
            if isunix
                system(['xdg-open ' filePath]);
            elseif ispc
                winopen(filePath);
            else
                msgbox('Open colocGUI_Help.pdf in the operating system');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Two-Color Colocalization';
            app.MoviesPanel = uipanel(app.UIFigure);
            app.MoviesPanel.Title = 'Movies';
            app.MovieList_ListBox = uilistbox(app.MoviesPanel);
            app.MovieList_ListBox.Items = {};
            app.MovieList_ListBox.Value = {};
            app.LoadML_Button = uibutton(app.MoviesPanel, 'push');
            app.LoadML_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadML_ButtonPushed, true);
            app.LoadML_Button.Text = 'Load List';
            app.LoadMD_Button = uibutton(app.MoviesPanel, 'push');
            app.LoadMD_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMD_ButtonPushed, true);
            app.LoadMD_Button.Text = 'Load Movie';
            app.ClearButton = uibutton(app.MoviesPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MovieData_ListBox = uilistbox(app.MoviesPanel);
            app.MovieData_ListBox.Items = {};
            app.MovieData_ListBox.Value = {};
            app.SaveasListButton = uibutton(app.MoviesPanel, 'push');
            app.SaveasListButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasListButtonPushed, true);
            app.SaveasListButton.Text = 'Save as List';
            app.ColocPanel = uipanel(app.UIFigure);
            app.ColocPanel.Title = 'Colocalization';
            app.DetectionProcessPanel = uipanel(app.ColocPanel);
            app.DetectionProcessPanel.Title = 'Detection Process';
            app.DP1_DropDown = uidropdown(app.DetectionProcessPanel);
            app.DP1_DropDown.Items = {'SubRes', 'PointSource'};
            app.DP1_DropDown.ValueChangedFcn = createCallbackFcn(app, @DP1_DropDownValueChanged, true);
            app.DP1_DropDown.Value = 'PointSource';
            app.DP2_DropDown = uidropdown(app.DetectionProcessPanel);
            app.DP2_DropDown.Items = {'SubRes', 'PointSource'};
            app.DP2_DropDown.ValueChangedFcn = createCallbackFcn(app, @DP2_DropDownValueChanged, true);
            app.DP2_DropDown.Value = 'PointSource';
            app.ColocRadiusPixelsPanel = uipanel(app.ColocPanel);
            app.ColocRadiusPixelsPanel.Title = 'Coloc Radius (Pixels)';
            app.SearchRadius_EditField = uieditfield(app.ColocRadiusPixelsPanel, 'numeric');
            app.SearchRadius_EditField.ValueChangedFcn = createCallbackFcn(app, @SearchRadius_EditFieldValueChanged, true);
            app.SearchRadius_EditField.Value = 3;
            app.RandomizationsPanel = uipanel(app.ColocPanel);
            app.RandomizationsPanel.Title = 'Randomizations';
            app.NumRand_EditField = uieditfield(app.RandomizationsPanel, 'numeric');
            app.NumRand_EditField.ValueChangedFcn = createCallbackFcn(app, @NumRand_EditFieldValueChanged, true);
            app.NumRand_EditField.Enable = 'off';
            app.NumRand_EditField.Value = 1;
            app.AnalysisFunctionPanel = uipanel(app.ColocPanel);
            app.AnalysisFunctionPanel.Title = 'Analysis Function';
            app.FunctionDropDown = uidropdown(app.AnalysisFunctionPanel);
            app.FunctionDropDown.Items = {'Pt2Pt', 'Pt2Blob', 'Blob2Blob', ''};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Pt2Pt';
            app.RunButton = uibutton(app.AnalysisFunctionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectChannelPanel = uipanel(app.ColocPanel);
            app.SelectChannelPanel.Title = 'Select Channel';
            app.ButtonGroup = uibuttongroup(app.SelectChannelPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonP1 = uiradiobutton(app.ButtonGroup);
            app.ButtonP1.Text = {'Punctate'; ''};
            app.ButtonP1.Value = true;
            app.ButtonN1 = uiradiobutton(app.ButtonGroup);
            app.ButtonN1.Text = {'Non Punctate'; ''};
            app.ChannelDropDown = uidropdown(app.SelectChannelPanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Value = {};
            app.SelectChannelPanel_2 = uipanel(app.ColocPanel);
            app.SelectChannelPanel_2.Title = 'Select Channel';
            app.ChannelDropDown_2 = uidropdown(app.SelectChannelPanel_2);
            app.ChannelDropDown_2.Items = {};
            app.ChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDown_2ValueChanged, true);
            app.ChannelDropDown_2.Enable = 'off';
            app.ChannelDropDown_2.Value = {};
            app.ButtonGroup_2 = uibuttongroup(app.SelectChannelPanel_2);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonP2 = uiradiobutton(app.ButtonGroup_2);
            app.ButtonP2.Text = 'Punctate';
            app.ButtonP2.Value = true;
            app.ButtonN2 = uiradiobutton(app.ButtonGroup_2);
            app.ButtonN2.Text = {'Non Punctate'; ''};
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultOptionsButton = uibutton(app.ResultsPanel, 'push');
            app.ResultOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultOptionsButtonPushed, true);
            app.ResultOptionsButton.Text = 'Result Options';
            app.ViewResultsButton = uibutton(app.ResultsPanel, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultsButtonPushed, true);
            app.ViewResultsButton.Enable = 'off';
            app.ViewResultsButton.Text = 'View Results';
            app.StatisticsPanel = uipanel(app.UIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.SaveTestsCheckBox = uicheckbox(app.StatisticsPanel);
            app.SaveTestsCheckBox.Text = 'Save Tests';
            app.StatisticalTestButton = uibutton(app.StatisticsPanel, 'push');
            app.StatisticalTestButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticalTestButtonPushed, true);
            app.StatisticalTestButton.Text = 'Statistical Test';
            app.PathEditFieldLabel = uilabel(app.StatisticsPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.StatisticsPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.Button = uibutton(app.StatisticsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.RunforAllCheckBox = uicheckbox(app.StatisticsPanel);
            app.RunforAllCheckBox.Text = 'Run for All';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = colocGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end