classdef colocGUIResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompiledResultOptionsUIFigure  matlab.ui.Figure
        OptionsApplytoCompiledResultsofallLoadedMoviesPanel  matlab.ui.container.Panel
        PlotCompiledResultCheckBox     matlab.ui.control.CheckBox
        SaveCompiledResultsCheckBox    matlab.ui.control.CheckBox
        ContinueButton                 matlab.ui.control.Button
        AnalysisFunctionDropDownLabel  matlab.ui.control.Label
        FunctionDropDown               matlab.ui.control.DropDown
        SelectChannelsToAnalyzePanel   matlab.ui.container.Panel
        ChannelDropDown                matlab.ui.control.DropDown
        ChannelDropDown_2              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp2
        savePath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, resPB, resPlot, saveRes, pathToSave)
            app.CallingApp2 = mainapp;
            % TP 240821: Automatically get current function value
            app.FunctionDropDown.Value = mainapp.FunctionDropDown.Value;
            % TP 240828: Fill Channels To Analyze Drop Downs with current
            % value in mainapp
            app.ChannelDropDown.Items = mainapp.ChannelDropDown.Items;
            app.ChannelDropDown.Value = mainapp.ChannelDropDown.Value;
            app.ChannelDropDown_2.Items = mainapp.ChannelDropDown_2.Items;
            app.ChannelDropDown_2.Value = mainapp.ChannelDropDown_2.Value;
            
            % Disable mainapp channeldropdowns to avoid weird user
            % interactions
            mainapp.ChannelDropDown.Enable = 'off';
            mainapp.ChannelDropDown_2.Enable = 'off';
            
            movegui(app.CompiledResultOptionsUIFigure,"center");  
        end

        % Value changed function: SaveCompiledResultsCheckBox
        function SaveCompiledResultsCheckBoxValueChanged(app, event)
            value = app.SaveCompiledResultsCheckBox.Value;
            if isequal(value,1)
                app.savePath = uigetdir(pwd, 'Select Folder to Save Compiled Results');
            end
            drawnow;
            figure(app.CompiledResultOptionsUIFigure);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            value = app.FunctionDropDown.Value;
            switch value
                case 'Pt2Pt'
                    resPB = 1;
                case 'Pt2Blob'
                    resPB = 2;
                case 'Blob2Blob'
                     resPB = 3;
            end
            
            updateResVar(app.CallingApp2, resPB, app.PlotCompiledResultCheckBox.Value,app.SaveCompiledResultsCheckBox.Value,...
                         app.savePath,[str2double(app.ChannelDropDown.Value) str2double(app.ChannelDropDown_2.Value)]);
            
            % Re-enable all disabled elements in mainapp when closing
            % dialog box
            app.CallingApp2.ViewResultsButton.Enable = 'on';
            app.CallingApp2.ChannelDropDown.Enable = 'on';
            app.CallingApp2.ChannelDropDown_2.Enable = 'on';
            
            drawnow;
            figure(app.CallingApp2.UIFigure);
            
            delete(app);
        end

        % Close request function: CompiledResultOptionsUIFigure
        function CompiledResultOptionsUIFigureCloseRequest(app, event)
            % Re-enable all disabled elements in mainapp when closing
            % dialog box
            app.CallingApp2.ResultOptionsButton.Enable = 'on';
            app.CallingApp2.ChannelDropDown.Enable = 'on';
            app.CallingApp2.ChannelDropDown_2.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompiledResultOptionsUIFigure = uifigure('Visible', 'off');
            app.CompiledResultOptionsUIFigure.Name = 'Compiled Result Options';
            app.CompiledResultOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CompiledResultOptionsUIFigureCloseRequest, true);
            app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel = uipanel(app.CompiledResultOptionsUIFigure);
            app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel.Title = 'Options Apply to Compiled Results of all Loaded Movies';
            app.PlotCompiledResultCheckBox = uicheckbox(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.PlotCompiledResultCheckBox.Text = 'Plot Compiled Result';
            app.SaveCompiledResultsCheckBox = uicheckbox(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.SaveCompiledResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCompiledResultsCheckBoxValueChanged, true);
            app.SaveCompiledResultsCheckBox.Text = 'Save Compiled Results';
            app.ContinueButton = uibutton(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.AnalysisFunctionDropDownLabel = uilabel(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.AnalysisFunctionDropDownLabel.Text = 'Analysis Function';
            app.FunctionDropDown = uidropdown(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.FunctionDropDown.Items = {'Pt2Pt', 'Pt2Blob', 'Blob2Blob'};
            app.FunctionDropDown.Value = 'Pt2Pt';
            app.SelectChannelsToAnalyzePanel = uipanel(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.SelectChannelsToAnalyzePanel.Title = 'Select Channels To Analyze';
            app.ChannelDropDown = uidropdown(app.SelectChannelsToAnalyzePanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.ChannelDropDown_2 = uidropdown(app.SelectChannelsToAnalyzePanel);
            app.ChannelDropDown_2.Items = {};
            app.ChannelDropDown_2.Value = {};
            % Show the figure after all components are created
            app.CompiledResultOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = colocGUIResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompiledResultOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompiledResultOptionsUIFigure)
        end
    end
end