classdef condColocGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HelpButton                matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        ColocalizationPanel       matlab.ui.container.Panel
        RandomizationsPanel       matlab.ui.container.Panel
        NumTR_EditField           matlab.ui.control.NumericEditField
        TarRefLabel               matlab.ui.control.Label
        ConditionLabel            matlab.ui.control.Label
        NumRand_EditField         matlab.ui.control.NumericEditField
        AnalysisFunctionPanel     matlab.ui.container.Panel
        FunctionDropDown          matlab.ui.control.DropDown
        ColocRadiusPixelsPanel    matlab.ui.container.Panel
        Ch2toCh3EditField_2Label  matlab.ui.control.Label
        ThreshCh2toCh3_EditField  matlab.ui.control.NumericEditField
        Ch1toCh3EditFieldLabel    matlab.ui.control.Label
        ThreshCh1toCh3_EditField  matlab.ui.control.NumericEditField
        Ch1toCh2EditFieldLabel    matlab.ui.control.Label
        ThreshCh1toCh2_EditField  matlab.ui.control.NumericEditField
        RunButton                 matlab.ui.control.Button
        DetectionProcessPanel     matlab.ui.container.Panel
        DP3_DropDown              matlab.ui.control.DropDown
        DP2_DropDown              matlab.ui.control.DropDown
        DP1_DropDown              matlab.ui.control.DropDown
        Channel3ButtonGroup       matlab.ui.container.ButtonGroup
        ButtonN3                  matlab.ui.control.RadioButton
        ButtonP3                  matlab.ui.control.RadioButton
        Channel2ButtonGroup       matlab.ui.container.ButtonGroup
        ButtonN2                  matlab.ui.control.RadioButton
        ButtonP2                  matlab.ui.control.RadioButton
        Channel1ButtonGroup       matlab.ui.container.ButtonGroup
        ButtonN1                  matlab.ui.control.RadioButton
        ButtonP1                  matlab.ui.control.RadioButton
        StatisticsPValuePanel     matlab.ui.container.Panel
        Panel_5                   matlab.ui.container.Panel
        SaveFiguresCheckBox       matlab.ui.control.CheckBox
        ViewStatTestButton        matlab.ui.control.Button
        Panel_4                   matlab.ui.container.Panel
        SaveTestsCheckBox         matlab.ui.control.CheckBox
        StatisticalTestButton     matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        RunforAllCheckBox         matlab.ui.control.CheckBox
        Button                    matlab.ui.control.Button
        PathEditField             matlab.ui.control.EditField
        PathEditFieldLabel        matlab.ui.control.Label
        ResultsPanel              matlab.ui.container.Panel
        Panel_2                   matlab.ui.container.Panel
        ViewResultsButton         matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        ResultOptionsButton       matlab.ui.control.Button
        MoviesPanel               matlab.ui.container.Panel
        SaveasListButton          matlab.ui.control.Button
        MovieData_ListBox         matlab.ui.control.ListBox
        ClearButton               matlab.ui.control.Button
        LoadMD_Button             matlab.ui.control.Button
        LoadML_Button             matlab.ui.control.Button
        MovieList_ListBox         matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        DialogApp 
        
        MD                      % MovieData Object Array
        moviePaths
        ML                      % MovieList Object Array
        listPaths
        
        CH1 = 'P';                     % Channel 1/2/3 (punctate or nonpunctate)
        CH2 = 'P';                   
        CH3 = 'P';
        
        PB1 = 1;                       % Point or Blob Channel used in the ColocGeneral function
        PB2 = 1;
        PB3 = 1;
                                 
        x = 1;                          % detected channels, blob channel - [x y], z
        y = 2; 
        z = 3;
        
        thresh1 = 3;                    %threshold values (default 3)
        thresh2 = 3;
        thresh3 = 3;
        
        dP1 = 'PointSource';            %detection process (default PointSource for coloc functions, but should have SubRes default)
        dP2 = 'PointSource';
        dP3 = 'PointSource';
        
        genDP1 = [];                    % detection process for colocGeneral functions
        genDP2 = [];
        genDP3 = [];
        
        value1;                         %drop down values global variable
        value2;
        value3;
        
        funcValue;                      % Function value drop down
        
        valueBox;                       
        
        numRand = 100;                  %number of randomizations variable (deafult 100 & 10)
        numTR = 10;
        
        userDir = pwd;
        
        % Results Options Variables
        resPB = 1;
        resPlot = 1;
        saveRes = 1;
        pathToSave = ' ';
        
        fullThresh = [];
        threshDir
        
    end
    
    methods (Access = private)    
        function refreshDisplay(app) %refreshes content inside list box
            app.moviePaths = arrayfun(@getFullPath,app.MD,'Unif',false);
            app.listPaths = arrayfun(@getFullPath,app.ML,'Unif',false);
            app.MovieData_ListBox.Items = {app.moviePaths{:}}; %#ok<*CCAT1> 
            app.MovieList_ListBox.Items = {app.listPaths{:}};
            return 
        end
        
        % analyzes the check boxes to see which channels are punctate vs nonpunctate
        function checkPunc(app)
            if app.CH1 == 'P'
                app.x = 1;
                if app.CH2 == 'P'
                    app.y = 2;
                    if app.CH3 == 'P'
                        app.z = 3;
                    end
                end
            end
            if app.CH1 == 'N'
                if app.CH2 == 'P'
                    if app.CH3 == 'P'
                        app.x = 2; 
                        app.y = 3;
                        app.z = 1;
                    end
                end
            end
            if app.CH1 == 'P'
                if app.CH2 == 'N'
                    if app.CH3 == 'P'
                        app.x = 1; 
                        app.y = 3; 
                        app.z = 2;
                    end
                end
            end
            if app.CH1 == 'N'
                if app.CH2 == 'N'
                    if app.CH3 == 'N'
                        app.x = 1; 
                        app.y = 2; 
                        app.z = 3;
                    end
                end
            end
        end 
        
        function checkDropDown(app)
            if app.CH1 == 'N'
                app.dP1 = '';
            end
            if app.CH2 == 'N'
                app.dP2 = '';
            end
            if app.CH3 == 'N'
                app.dP3 = '';
            end
            
        end
        
        function checkFunc(app)
            if (app.CH1 == 'P') && (app.CH2 == 'P') && (app.CH3 == 'P')
                app.FunctionDropDown.Items = {'Pt2Pt2Pt', 'General'};
            elseif (app.CH1 == 'N') && (app.CH2 == 'N') && (app.CH3 == 'N')
                app.FunctionDropDown.Items = {'General'};
            elseif((app.CH1 == 'P') && (app.CH2 == 'P') && (app.CH3 == 'N')) || ...
               ((app.CH1 == 'P') && (app.CH2 == 'N') && (app.CH3 == 'P')) || ...
               ((app.CH1 == 'N') && (app.CH2 == 'P') && (app.CH3 == 'P'))
                app.FunctionDropDown.Items = {'Pt2Pt2Blob','General'};
            else
                app.FunctionDropDown.Items = {'General'};
                app.NumTR_EditField.Enable = 'on';
            end
            app.funcValue = app.FunctionDropDown.Value;
        end
        
        function saveList(app) %turns movie data into movie list
            if isempty(app.MD)
                warndlg('No movie selected. Please create new movie data or open existing movie data or movie list.', 'No Movie Selected', 'modal')
                return
            end
            
            if isempty(app.ML)
                movieListPath = [app.userDir filesep];
                movieListFileName = 'movieList.mat';
            else
                movieListPath = app.ML(end).movieListPath_;
                movieListFileName = app.ML(end).movieListFileName_;
            end
            
            [fileName,path] = uiputfile('*.mat','Find a place to save your movie list',...
             [movieListPath filesep movieListFileName]);
            if ~any([fileName,path]), return; end
            
            outputDir = uigetdir(path,'Select a directory to store the list analysis output');
            if isequal(outputDir,0), return; end
            
            try
                tempML = MovieList(app.MD, outputDir);
                tempML.setPath(path);
                tempML.setFilename(fileName);
                tempML.sanityCheck;
            catch ME
                msg = sprintf('%s\n\nMovie list is not saved.', ME.message);
                errordlg(msg, 'Movie List Error', 'modal')
                return
            end
            app.ML = horzcat(app.ML, tempML);
        end
    
    end
    
    methods (Access = public)
        
        function updateResVar(app, resPB, resPlot, saveRes, pathToSave) % used in result options to update parameters
            app.resPB = resPB;
            app.resPlot = resPlot;
            app.saveRes = saveRes;
            app.pathToSave = pathToSave; 
            app.ResultOptionsButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");                 % Centers application to middle of screen
            
            app.MD = MovieData.empty(1,0);                                     % Creates empty array of MovieData objects
            app.ML = MovieList.empty(1,0);                                     % Creates empty array of MovieList objects
            
            checkFunc(app);
  
        end

        % Button pushed function: LoadMD_Button
        function LoadMD_ButtonPushed(app, event)
            filespec = {'*.mat','MATLAB Files'};
            [fileName,filePath] = uigetfile(filespec, 'Select a Movie to load', app.userDir);                    % Opens Dialogue Box for File Selection 
            drawnow;
            figure(app.UIFigure);
            
            if fileName == 0
                errordlg('No file was input' , 'File Selection Error','modal');
                return
            end
            
            if ~any([fileName filePath]), return; end
            app.userDir = filePath;
            file = [filePath fileName];
            
            if any(strcmp([filePath fileName], app.moviePaths))
                errordlg('This movie has already been selected.','File Selection Error','modal');
                return
            end
            
            try
                movieData = MovieData.load(file);                   % gets MovieData object from the .mat file
                app.MD = horzcat(app.MD, movieData);                % adds MovieData object to array of MovieData
            catch ME
                msg = sprintf('Movie: %s\n\nError: %s\n\nMovie is not successfully loaded. Please refer to movie detail and adjust your data.', [filePath fileName],ME.message);
                errordlg(msg, 'Movie error','modal');
                return
            end
            
            %240903 TP: Set initial stat path to MD output directory
            app.PathEditField.Value = app.MD.outputDirectory_;

            app.refreshDisplay();                           % updates items in Movie Data listbox 
        end

        % Button pushed function: LoadML_Button
        function LoadML_ButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.mat');                      % Opens Dialogue Box for File Selection
            drawnow;
            figure(app.UIFigure);
            file = [filePath fileName];
            
            if fileName == 0
                %warning("No file was input - returning")
                errordlg('No file was input' , 'File Selection Error','modal');
                return
            end
            
            if any(strcmp([filePath fileName], app.listPaths))
                errordlg('This movie list has already been opened.','File Selection Error','modal');
                return
            end
            
            try
                movieList = MovieList.load(file);                            % gets MovieList object from the .mat file
                app.ML = horzcat(app.ML,movieList);                          % adds MovieList object to array of MovieData
            catch ME
                msg = sprintf('MovieList Error: \n\nList is not successfully loaded. Please refer to list detail and adjust your data.\n\n%s\n\n%s', [filePath fileName],ME.message);
                errordlg(msg, 'MovieList Error','modal');
                return
            end
            
            
            % adds movies from MovieList to MovieData Listbox/MD array
            for i = 1:length(movieList.movieDataFile_)
                dataFile = movieList.movieDataFile_(i);        
                MDobject = load(char(dataFile));
                tempMD = MDobject.MD;
                app.MD = horzcat(app.MD, tempMD);
            end
            
            %240903 TP: Set initial stat path to MD output directory
            app.PathEditField.Value = app.ML.outputDirectory_;
            
            app.refreshDisplay();  
        end

        % Button pushed function: SaveasListButton
        function SaveasListButtonPushed(app, event)
            if isempty(app.moviePaths)
                errordlg('No movies loaded. Please load movie data first to create a new movie list.', 'Saving Error', 'modal');
                return;
            end
            saveList(app);
            refreshDisplay(app);
            
            drawnow;
            figure(app.UIFigure)
         
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Confirm Clear
            user_response = questdlg('Are you sure to delete all the movies and movie lists?','Movie Listbox', 'Yes','No','Yes');
            if strcmpi('no', user_response), return; end
            
            % Clear List Boxes
            app.MD = MovieData.empty(1,0);
            app.ML = MovieList.empty(1,0);
            
            % Clear Path Field Value
            app.PathEditField.Value = '';
            
            % Diable View Results Option
            app.ViewResultsButton.Enable = 'off';
            
            app.refreshDisplay();
        end

        % Selection changed function: Channel1ButtonGroup
        function Channel1ButtonGroupSelectionChanged(app, event)
            selectedButton1 = app.Channel1ButtonGroup.SelectedObject;
            
            if selectedButton1 == app.ButtonP1
                app.DP1_DropDown.Enable = 'on';
                app.CH1 = 'P';
                app.PB1 = 1;
            else
                app.DP1_DropDown.Enable = 'off';
                app.CH1 = 'N';
                app.PB1 = 0;
            end
            checkPunc(app);
            checkFunc(app);
        end

        % Selection changed function: Channel2ButtonGroup
        function Channel2ButtonGroupSelectionChanged(app, event)
            selectedButton2 = app.Channel2ButtonGroup.SelectedObject;
            
            if selectedButton2 == app.ButtonP2
                app.DP2_DropDown.Enable = 'on';
                app.CH2 = 'P';
                app.PB2 = 1;
            else
                app.DP2_DropDown.Enable = 'off';
                app.CH2 = 'N';
                app.PB2 = 0;
            end
            checkPunc(app);
            checkFunc(app);
        end

        % Selection changed function: Channel3ButtonGroup
        function Channel3ButtonGroupSelectionChanged(app, event)
            selectedButton3 = app.Channel3ButtonGroup.SelectedObject;
            
            if selectedButton3 == app.ButtonP3
                app.DP3_DropDown.Enable = 'on';
                app.CH3 = 'P';
                app.PB3 = 1;
            else
                app.DP3_DropDown.Enable = 'off';
                app.CH3 = 'N';
                app.PB3 = 0;
            end
            checkPunc(app);
            checkFunc(app);
        end

        % Value changed function: ThreshCh1toCh2_EditField
        function ThreshCh1toCh2_EditFieldValueChanged(app, event)
            % Ch1 to Ch2 X to Y
            value = app.ThreshCh1toCh2_EditField.Value;
            app.thresh1 = value;
        end

        % Value changed function: ThreshCh1toCh3_EditField
        function ThreshCh1toCh3_EditFieldValueChanged(app, event)
            % Ch1 to Ch3 X to Z
            value = app.ThreshCh1toCh3_EditField.Value;
            app.thresh2 = value;
        end

        % Value changed function: ThreshCh2toCh3_EditField
        function ThreshCh2toCh3_EditFieldValueChanged(app, event)
            % Ch2 to Ch3 Y to Z
            value = app.ThreshCh2toCh3_EditField.Value;
            app.thresh3 = value;
        end

        % Value changed function: NumRand_EditField
        function NumRand_EditFieldValueChanged(app, event)
            value = app.NumRand_EditField.Value;
            app.numRand = value;
        end

        % Value changed function: NumTR_EditField
        function NumTR_EditFieldValueChanged(app, event)
            value = app.NumTR_EditField.Value;
            app.numTR = value;
        end

        % Value changed function: DP1_DropDown
        function DP1_DropDownValueChanged(app, event)
            app.value1 = app.DP1_DropDown.Value;
            app.dP1 = app.value1;
            if app.CH1 == 'N'
                app.dP1 = '';
            end
        end

        % Value changed function: DP2_DropDown
        function DP2_DropDownValueChanged(app, event)
            app.value2 = app.DP2_DropDown.Value;
            app.dP2 = app.value2;
            if app.CH2 == 'N'
                app.dP2 = '';
            end
        end

        % Value changed function: DP3_DropDown
        function DP3_DropDownValueChanged(app, event)
            app.value3 = app.DP3_DropDown.Value;
            app.dP3 = app.value3;
            if app.CH3 == 'N'
                app.dP3 = '';
            end
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            app.funcValue = app.FunctionDropDown.Value;
            switch app.funcValue
                case 'General'
                    app.NumTR_EditField.Enable = 'on';
                    app.resPB = 3;
                case 'Pt2Pt2Pt'
                    app.NumTR_EditField.Enable = 'off';
                    app.resPB = 1;
                case 'Pt2Pt2Blob'
                    app.NumTR_EditField.Enable = 'off';
                    app.resPB = 2;
            end
            
        end

        % Value changed function: MovieData_ListBox
        function MovieData_ListBoxValueChanged(app, event)
            value = app.MovieData_ListBox.Value;
            app.valueBox = value;
            disp(app.valueBox);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.moviePaths)
                errordlg('No movies loaded. Please load movie data before running.','Run Analysis Error', "modal");
                return;
            end
            
            % Formatting Console Window
            fprintf('\nRunning Analysis\n\n');
            
            % Running any of the 3 functions
            try
                switch app.FunctionDropDown.Value
                    case 'Pt2Pt2Pt'
                        disp('Pt2Pt2Pt Analysis')
                        for i = 1:length(app.MD)
                            formatSpec = 'Movie %d:';
                            str = sprintf(formatSpec,i);
                            disp(str); %#ok<DSPS>
                            condColocAnalysisPt2Pt2PtMLMD(app.MD(i), 'detectionProcessID', {app.dP1 app.dP2 app.dP3}, 'colocDistThresh', [app.thresh1 app.thresh2 app.thresh3], 'numRandomizations', app.numRand);
                            disp(' ');
                        end
                        msgBox("Pt2Pt2Pt Colocalization Analysis Complete","Colocalization","none");
                    case 'Pt2Pt2Blob'
                        disp('Pt2Pt2Blob Analysis')
                        for i = 1:length(app.MD)
                            formatSpec = 'Movie %d:';
                            str = sprintf(formatSpec,i);
                            disp(str); %#ok<DSPS>
                            condColocAnalysisPt2Pt2BlobMLMD(app.MD(i), [app.x app.y], app.z, 'detectionProcessID', {app.dP1 app.dP2}, 'colocDistThresh', [app.thresh1 app.thresh2 app.thresh3], 'numRandomizations', app.numRand);
                            disp(' ');
                        end
                        msgBox("Pt2Pt2Blob Colocalization Analysis Complete","Colocalization","none");
                    case 'General'
                        disp('General Analysis')
                        if app.CH1 == 'N'
                            app.genDP1 = [];
                        else
                            app.genDP1 = app.dP1;
                        end
                        if app.CH2 == 'N'
                            app.genDP2 = [];
                        else
                            app.genDP2 = app.dP2;
                        end
                        if app.CH3 == 'N'
                            app.genDP3 = [];
                        else
                            app.genDP3 = app.dP3;
                        end
                        
                        for i = 1:length(app.MD)
                            formatSpec = 'Movie %d:';
                            str = sprintf(formatSpec,i);
                            disp(str); %#ok<DSPS>
                            condColocGeneralAnalysisMLMD(app.MD(i), [app.PB1 app.PB2 app.PB3], 'detectionProcessID', {app.genDP1 app.genDP2 app.genDP3}, 'colocDistThresh', [app.thresh1 app.thresh2 app.thresh3], 'numTarRefRand', 1, 'numCondRand', app.numRand);
                            disp(' ');
                        end
                        msgBox("General Colocalization Analysis Complete","Colocalization","none");
                end
                
                if length(app.MD) > 1
                    disp('Analysis Completed');
                    disp(' ');
                    disp(' ');
                end
            
            catch ME
                msg = sprintf('Run Analysis Error: \n\nUnable to perform analysis. \nPlease make sure each option has been selected and matches the loaded data.\n\n%s',ME.message);
                errordlg(msg, 'Run Analysis Error','modal');
                return
            end
            
            figure(app.UIFigure);

        end

        % Button pushed function: ResultOptionsButton
        function ResultOptionsButtonPushed(app, event)
            if isempty(app.listPaths)
                errordlg('No movie list loaded. Please load or create a new movie list first.', 'View Results Error', 'modal');
                return;
            end
            
            % app.ResultOptionsButton.Enable = 'off';
            
            app.DialogApp = condColocGUIResults(app, app.resPB, app.resPlot, app.saveRes, app.pathToSave);
        end

        % Button pushed function: ViewResultsButton
        function ViewResultsButtonPushed(app, event)
            
            try
                switch app.funcValue
                case 'Pt2Pt2Pt'
                    iProc = app.MD(1).getProcessIndex('CondColocPt2Pt2PtProcess',1,0);
                case 'Pt2Pt2Blob'
                    iProc = app.MD(1).getProcessIndex('CondColocPt2Pt2BlobProcess',1,0);
                case 'General'
                    iProc = app.MD(1).getProcessIndex('CondColocGeneralProcess',1,0);
                end
            catch 
                msg = sprintf('Please ensure that a selection has been \nmade in the Analysis Function Drop Down.');
                errordlg(msg, 'Results Error','modal');
                return
            end
            
            % multiple lists

            mlCell = cell(1,length(app.ML));
            
            for i = 1:length(app.ML)
                mlCell{1,i} = app.ML(i); 
            end
            
            % running plotting/saving function
            if isequal(app.saveRes,0) %not saved
                plotSaveConditionalColoc(app.resPB,app.resPlot,app.saveRes,[],1,mlCell{1,:}); %1 because in GUI
            else %saved
                %creating subfolders
                app.fullThresh = app.MD(1).processes_{1,iProc}.funParams_.ColocDistThresh();
                folderSpec = 'Threshold_%d%d%d';
                fld = sprintf(folderSpec, app.fullThresh);
                
                mkdir(app.pathToSave,fld);
                app.threshDir = fullfile(app.pathToSave,fld);
                
                %save function
                plotSaveConditionalColoc(app.resPB,app.resPlot,app.saveRes,app.threshDir,1,mlCell{1,:}); %1 because in GUI
                
                % Make Stat Path the same as new folder created and notify
                % if saved
                app.PathEditField.Value = app.threshDir;
                
                %movie result into subfolder
                % formatSpec = '_%d%d%d';
                % fd = fileDatastore(app.threshDir,"ReadFcn",@load,"FileExtensions",".mat");
                % colocArray = fd.Files;
                % TF = contains(colocArray, 'colocalMeasure');
                % colocArray = colocArray(TF);
                
                % Ignore files with the threshold numbers already in its name
                % threshNums = [sprintf(formatSpec,app.fullThresh) '.mat'];
                % newFilesLogi = ~contains(colocArray,threshNums);
                % colocArray = colocArray(newFilesLogi);
                
                % for i = 1:numel(colocArray)
                %     [p,f,e] = fileparts(colocArray{i});
                %     threshExt = sprintf(formatSpec,app.fullThresh);
                %     fileTo = [p,'/',f,threshExt,e];
                %     movefile(colocArray{i},fileTo);
                % end
            end


            fprintf('\nResults Completed\n');
            
            msgboxMsg = sprintf("Compiled Results Completed\n\nStat Test Path is %s\n", app.PathEditField.Value);
            
            msgbox(msgboxMsg,"Compiled Results","none");
            
        end

        % Button pushed function: StatisticalTestButton
        function StatisticalTestButtonPushed(app, event)
            % if isempty(app.listPaths)
            %    errordlg('No movie list loaded. Please load or create a new movie list first.', 'View Results Error', 'modal');
            %    return;
            % end
            
            % reading through subfolder for result file
            % fds = fileDatastore(app.threshDir,"ReadFcn",@load,"FileExtensions",".mat");
            
            % 20240903 TP: Making Stat Test Independent
            % app.PathEditField.Value is where the files will be saved
            
            if isequal(app.RunforAllCheckBox.Value, 1)
                fds = fileDatastore(app.PathEditField.Value,"ReadFcn",@load,"FileExtensions",".mat");
                resArray = fds.Files;
                TF = contains(resArray, 'colocalMeasure');
                resArray = resArray(TF);
                
                % performing stat test
                if isequal(app.SaveTestsCheckBox.Value,1)
                    for i = 1:length(resArray)
                        condColocStatTest(1,resArray{i},app.PathEditField.Value);
                    end
                    msgbox("Statistic Test Completed","Statistic Test","help","modal");
                 
                else % if not saving, ...
                    for i = 1:length(resArray)
                        tempSplitArr = strsplit(resArray{i},'Measure');
                        tempNameArray = strsplit(tempSplitArr{end},'.');
                        figName = tempNameArray{1};
                        
                        tbRes = condColocStatTest(0, resArray{i},app.PathEditField.Value);
                        fig = figure('Name', figName);
                        uitable(fig, 'Data',tbRes{:,:},'ColumnName',tbRes.Properties.VariableNames,...
                        'RowName',tbRes.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                    end
                end
            else
                if isequal(app.SaveTestsCheckBox.Value, 1)
                    condColocStatTest(1,[],app.PathEditField.Value);
                    msgbox("Statistic Test Completed","Statistic Test","help","modal");
                else
                    tbRes = condColocStatTest(0);
                    uitable('Data',tbRes{:,:},'ColumnName',tbRes.Properties.VariableNames,...
                    'RowName',tbRes.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                end
            end
            
            fprintf('\nStatistic Test Completed\n');
  
        end

        % Button pushed function: ViewStatTestButton
        function ViewStatTestButtonPushed(app, event)
            %if isempty(app.listPaths)
            %    errordlg('No movie list loaded. Please load or create a new movie list first.', 'View Results Error', 'modal');
            %    return;
            % end
            
            if isequal(app.RunforAllCheckBox.Value, 1)
                fds = fileDatastore(app.PathEditField.Value,"ReadFcn",@load,"FileExtensions",".mat");
                resArray = fds.Files;
                TF = contains(resArray, 'statTest');
                resArray = resArray(TF);

                for i = 1:length(resArray)
                    plotCondColocPvalues(app.SaveFiguresCheckBox.Value,[-15 15],resArray{i},app.PathEditField.Value);
                end
                % if isequal(app.SaveFiguresCheckBox.Value,1)
                %    for i = 1:length(resArray)
                %        plotCondColocPvalues(1,[-15 15],resArray{i},app.PathEditField.Value);
                %    end
                % else
                %    plotCondColocPvalues(0,[-15 15],resArray{i});
                % end
            else
                plotCondColocPvalues(app.SaveFiguresCheckBox.Value,[-15 15],[],app.PathEditField.Value);
            end
            
            msgbox("View Stat Test Complete", "View Stat Test", "help", "modal");
            figure(app.UIFigure);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = uigetdir(app.PathEditField.Value, 'Select Folder For Statistical Test');
            if ~isequal(value, 0)
                app.PathEditField.Value = value;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % 240904 TP: Updated to use ~ to bypass unique user folders
            %if isunix
            %    system(['evince ' '~/matlab/applications/receptors/colocalization/GUI/doc/condColocGUI_Help.pdf' ' &']);
            %elseif ispc
            %    open('~/matlab/applications/receptors/colocalization/GUI/condColoc/doc/condColocGUI_Help.pdf');
            %else
            %    msgbox(['Open ' 'condColocGUI_Help.pdf' 'in the operating system'],'Product Help');
            %end
            
            % 240911 TP: Gets path of condColocGUI, backs into the GUI folder, into the
            % doc folder to get the correct pdf
            
            if isunix
                delimiter = '/';
            elseif ispc
                delimiter = '\';
            else
                msgbox('Unknown Operating System');
            end
            
            currPath = mfilename('fullpath');
            tempSplit = split(currPath, delimiter);
            newTempPath = join(tempSplit(1:end-1), delimiter);
            filePath = fullfile(newTempPath{1}, 'doc', 'condColocGUI_Help.pdf');
            
            if isunix
                system(['xdg-open ' filePath]);
            elseif ispc
                winopen(filePath);
            else
                msgbox('Open colocGUI_Help.pdf in the operating system');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Three-Color Conditional Colocalization';
            app.MoviesPanel = uipanel(app.UIFigure);
            app.MoviesPanel.Title = 'Movies';
            app.MovieList_ListBox = uilistbox(app.MoviesPanel);
            app.MovieList_ListBox.Items = {};
            app.MovieList_ListBox.Value = {};
            app.LoadML_Button = uibutton(app.MoviesPanel, 'push');
            app.LoadML_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadML_ButtonPushed, true);
            app.LoadML_Button.Text = 'Load List';
            app.LoadMD_Button = uibutton(app.MoviesPanel, 'push');
            app.LoadMD_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMD_ButtonPushed, true);
            app.LoadMD_Button.Text = 'Load Movie';
            app.ClearButton = uibutton(app.MoviesPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MovieData_ListBox = uilistbox(app.MoviesPanel);
            app.MovieData_ListBox.Items = {};
            app.MovieData_ListBox.ValueChangedFcn = createCallbackFcn(app, @MovieData_ListBoxValueChanged, true);
            app.MovieData_ListBox.Value = {};
            app.SaveasListButton = uibutton(app.MoviesPanel, 'push');
            app.SaveasListButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasListButtonPushed, true);
            app.SaveasListButton.Text = 'Save as List';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.Panel = uipanel(app.ResultsPanel);
            app.ResultOptionsButton = uibutton(app.Panel, 'push');
            app.ResultOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultOptionsButtonPushed, true);
            app.ResultOptionsButton.Text = 'Results Options';
            app.Panel_2 = uipanel(app.ResultsPanel);
            app.ViewResultsButton = uibutton(app.Panel_2, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultsButtonPushed, true);
            app.ViewResultsButton.Enable = 'off';
            app.ViewResultsButton.Text = 'View Results';
            app.StatisticsPValuePanel = uipanel(app.UIFigure);
            app.StatisticsPValuePanel.Title = 'Statistics/P-Value';
            app.Panel_3 = uipanel(app.StatisticsPValuePanel);
            app.PathEditFieldLabel = uilabel(app.Panel_3);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.Panel_3, 'text');
            app.PathEditField.Editable = 'off';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.RunforAllCheckBox = uicheckbox(app.Panel_3);
            app.RunforAllCheckBox.Text = 'Run for All';
            app.Panel_4 = uipanel(app.StatisticsPValuePanel);
            app.StatisticalTestButton = uibutton(app.Panel_4, 'push');
            app.StatisticalTestButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticalTestButtonPushed, true);
            app.StatisticalTestButton.Text = 'Statistical Test';
            app.SaveTestsCheckBox = uicheckbox(app.Panel_4);
            app.SaveTestsCheckBox.Text = 'Save Tests';
            app.Panel_5 = uipanel(app.StatisticsPValuePanel);
            app.ViewStatTestButton = uibutton(app.Panel_5, 'push');
            app.ViewStatTestButton.ButtonPushedFcn = createCallbackFcn(app, @ViewStatTestButtonPushed, true);
            app.ViewStatTestButton.Text = 'View Stat Test';
            app.SaveFiguresCheckBox = uicheckbox(app.Panel_5);
            app.SaveFiguresCheckBox.Text = 'Save Figures';
            app.ColocalizationPanel = uipanel(app.UIFigure);
            app.ColocalizationPanel.Title = 'Colocalization';
            app.Channel1ButtonGroup = uibuttongroup(app.ColocalizationPanel);
            app.Channel1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel1ButtonGroupSelectionChanged, true);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.ButtonP1 = uiradiobutton(app.Channel1ButtonGroup);
            app.ButtonP1.Text = 'Punctate';
            app.ButtonP1.Value = true;
            app.ButtonN1 = uiradiobutton(app.Channel1ButtonGroup);
            app.ButtonN1.Text = 'Non Punctate';
            app.Channel2ButtonGroup = uibuttongroup(app.ColocalizationPanel);
            app.Channel2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel2ButtonGroupSelectionChanged, true);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.ButtonP2 = uiradiobutton(app.Channel2ButtonGroup);
            app.ButtonP2.Text = 'Punctate';
            app.ButtonP2.Value = true;
            app.ButtonN2 = uiradiobutton(app.Channel2ButtonGroup);
            app.ButtonN2.Text = 'Non Punctate';
            app.Channel3ButtonGroup = uibuttongroup(app.ColocalizationPanel);
            app.Channel3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel3ButtonGroupSelectionChanged, true);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.ButtonP3 = uiradiobutton(app.Channel3ButtonGroup);
            app.ButtonP3.Text = 'Punctate';
            app.ButtonP3.Value = true;
            app.ButtonN3 = uiradiobutton(app.Channel3ButtonGroup);
            app.ButtonN3.Text = 'Non Punctate';
            app.DetectionProcessPanel = uipanel(app.ColocalizationPanel);
            app.DetectionProcessPanel.Title = 'Detection Process';
            app.DP1_DropDown = uidropdown(app.DetectionProcessPanel);
            app.DP1_DropDown.Items = {'PointSource', 'SubRes'};
            app.DP1_DropDown.ValueChangedFcn = createCallbackFcn(app, @DP1_DropDownValueChanged, true);
            app.DP1_DropDown.Value = 'PointSource';
            app.DP2_DropDown = uidropdown(app.DetectionProcessPanel);
            app.DP2_DropDown.Items = {'PointSource', 'SubRes'};
            app.DP2_DropDown.ValueChangedFcn = createCallbackFcn(app, @DP2_DropDownValueChanged, true);
            app.DP2_DropDown.Value = 'PointSource';
            app.DP3_DropDown = uidropdown(app.DetectionProcessPanel);
            app.DP3_DropDown.Items = {'PointSource', 'SubRes'};
            app.DP3_DropDown.ValueChangedFcn = createCallbackFcn(app, @DP3_DropDownValueChanged, true);
            app.DP3_DropDown.Value = 'PointSource';
            app.RunButton = uibutton(app.ColocalizationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ColocRadiusPixelsPanel = uipanel(app.ColocalizationPanel);
            app.ColocRadiusPixelsPanel.Title = 'Coloc Radius (Pixels)';
            app.ThreshCh1toCh2_EditField = uieditfield(app.ColocRadiusPixelsPanel, 'numeric');
            app.ThreshCh1toCh2_EditField.ValueChangedFcn = createCallbackFcn(app, @ThreshCh1toCh2_EditFieldValueChanged, true);
            app.ThreshCh1toCh2_EditField.Value = 3;
            app.Ch1toCh2EditFieldLabel = uilabel(app.ColocRadiusPixelsPanel);
            app.Ch1toCh2EditFieldLabel.Text = 'Ch1 to Ch2';
            app.ThreshCh1toCh3_EditField = uieditfield(app.ColocRadiusPixelsPanel, 'numeric');
            app.ThreshCh1toCh3_EditField.ValueChangedFcn = createCallbackFcn(app, @ThreshCh1toCh3_EditFieldValueChanged, true);
            app.ThreshCh1toCh3_EditField.Value = 3;
            app.Ch1toCh3EditFieldLabel = uilabel(app.ColocRadiusPixelsPanel);
            app.Ch1toCh3EditFieldLabel.Text = 'Ch1 to Ch3';
            app.ThreshCh2toCh3_EditField = uieditfield(app.ColocRadiusPixelsPanel, 'numeric');
            app.ThreshCh2toCh3_EditField.ValueChangedFcn = createCallbackFcn(app, @ThreshCh2toCh3_EditFieldValueChanged, true);
            app.ThreshCh2toCh3_EditField.Value = 3;
            app.Ch2toCh3EditField_2Label = uilabel(app.ColocRadiusPixelsPanel);
            app.Ch2toCh3EditField_2Label.Text = 'Ch2 to Ch3';
            app.AnalysisFunctionPanel = uipanel(app.ColocalizationPanel);
            app.AnalysisFunctionPanel.Title = 'Analysis Function';
            app.FunctionDropDown = uidropdown(app.AnalysisFunctionPanel);
            app.FunctionDropDown.Items = {'Pt2Pt2Pt', 'Pt2Pt2Blob', 'General'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Pt2Pt2Pt';
            app.RandomizationsPanel = uipanel(app.ColocalizationPanel);
            app.RandomizationsPanel.Title = 'Randomizations';
            app.NumRand_EditField = uieditfield(app.RandomizationsPanel, 'numeric');
            app.NumRand_EditField.ValueChangedFcn = createCallbackFcn(app, @NumRand_EditFieldValueChanged, true);
            app.NumRand_EditField.Value = 100;
            app.ConditionLabel = uilabel(app.RandomizationsPanel);
            app.ConditionLabel.Text = 'Condition';
            app.TarRefLabel = uilabel(app.RandomizationsPanel);
            app.TarRefLabel.Text = 'Tar / Ref';
            app.NumTR_EditField = uieditfield(app.RandomizationsPanel, 'numeric');
            app.NumTR_EditField.ValueChangedFcn = createCallbackFcn(app, @NumTR_EditFieldValueChanged, true);
            app.NumTR_EditField.Enable = 'off';
            app.NumTR_EditField.Value = 10;
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = condColocGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end