classdef condColocGUIResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompiledResultOptionsUIFigure  matlab.ui.Figure
        OptionsApplytoCompiledResultsofallLoadedMoviesPanel  matlab.ui.container.Panel
        PlotCompiledResultsCheckBox    matlab.ui.control.CheckBox
        SaveCompiledResultsCheckBox    matlab.ui.control.CheckBox
        ContinueButton                 matlab.ui.control.Button
        AnalysisFunctionDropDownLabel  matlab.ui.control.Label
        FunctionDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp
        
        savePath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, resPB, resPlot, saveRes, pathToSave)
            app.CallingApp = mainapp;
            
            % TP 240830: Automatically get the value from the mainapp
            % function drop down
            app.FunctionDropDown.Items = app.CallingApp.FunctionDropDown.Items;
            app.FunctionDropDown.Value = app.CallingApp.FunctionDropDown.Value;
            
            movegui(app.CompiledResultOptionsUIFigure,"center");  
        end

        % Value changed function: SaveCompiledResultsCheckBox
        function SaveCompiledResultsCheckBoxValueChanged(app, event)
            value = app.SaveCompiledResultsCheckBox.Value;
            if isequal(value,1)
                app.savePath = uigetdir(pwd, 'Select Folder to Save Compiled Results');
            end
            drawnow;
            figure(app.CompiledResultOptionsUIFigure);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            value = app.FunctionDropDown.Value;
            switch value
                case 'Pt2Pt2Pt'
                    resPB = 1;
                case 'Pt2Pt2Blob'
                    resPB = 2;
                case 'General'
                    resPB = 3;
            end
            
            updateResVar(app.CallingApp, resPB, app.PlotCompiledResultsCheckBox.Value, app.SaveCompiledResultsCheckBox.Value, app.savePath);
            
            app.CallingApp.ViewResultsButton.Enable = 'on';
            
            drawnow;
            figure(app.CallingApp.UIFigure);
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompiledResultOptionsUIFigure = uifigure('Visible', 'off');
            app.CompiledResultOptionsUIFigure.Name = 'Compiled Result Options';
            app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel = uipanel(app.CompiledResultOptionsUIFigure);
            app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel.Title = 'Options Apply to Compiled Results of all Loaded Movies';
            app.PlotCompiledResultsCheckBox = uicheckbox(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.PlotCompiledResultsCheckBox.Text = 'Plot Compiled Results';
            app.SaveCompiledResultsCheckBox = uicheckbox(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.SaveCompiledResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCompiledResultsCheckBoxValueChanged, true);
            app.SaveCompiledResultsCheckBox.Text = 'Save Compiled Results';
            app.ContinueButton = uibutton(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.AnalysisFunctionDropDownLabel = uilabel(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.AnalysisFunctionDropDownLabel.Text = 'Analysis Function';
            app.FunctionDropDown = uidropdown(app.OptionsApplytoCompiledResultsofallLoadedMoviesPanel);
            app.FunctionDropDown.Items = {'Pt2Pt2Pt', 'Pt2Pt2Blob', 'General'};
            app.FunctionDropDown.Value = 'Pt2Pt2Blob';
            % Show the figure after all components are created
            app.CompiledResultOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = condColocGUIResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompiledResultOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompiledResultOptionsUIFigure)
        end
    end
end